classdef ANN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ANN_app                        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InputandClassifyTab            matlab.ui.container.Tab
        OutputPanel                    matlab.ui.container.Panel
        WritetoCSVButton               matlab.ui.control.Button
        ClassifyButton                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        InputPanel                     matlab.ui.container.Panel
        LoadCSVButton                  matlab.ui.control.Button
        UndoButton                     matlab.ui.control.Button
        AddPointButton                 matlab.ui.control.Button
        X2EditField                    matlab.ui.control.NumericEditField
        X2EditFieldLabel               matlab.ui.control.Label
        X1EditField                    matlab.ui.control.NumericEditField
        X1EditFieldLabel               matlab.ui.control.Label
        VisualizeTab                   matlab.ui.container.Tab
        redoutsideLabel                matlab.ui.control.Label
        greeninsideLabel               matlab.ui.control.Label
        ShowtrainingdataButton         matlab.ui.control.Button
        redincorrectlyclassifiedLabel  matlab.ui.control.Label
        greencorrectlyclassifiedLabel  matlab.ui.control.Label
        VisualizeButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        TrainTab                       matlab.ui.container.Tab
        ChooseanetworkPanel            matlab.ui.container.Panel
        UsethedefaultnetworkButton     matlab.ui.control.Button
        UsethetrainednetworkButton     matlab.ui.control.Button
        TrainthenetworkPanel           matlab.ui.container.Panel
        TrainButton                    matlab.ui.control.StateButton
        NumberofneuronsinhiddenlayerEditField  matlab.ui.control.NumericEditField
        NumberofneuronsinhiddenlayerEditFieldLabel  matlab.ui.control.Label
        GeneratedatafortrainingPanel   matlab.ui.container.Panel
        GenerateButton                 matlab.ui.control.StateButton
        NumberofpointsfortrainingEditField  matlab.ui.control.NumericEditField
        NumberofpointsfortrainingEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        x_1             % x1
        x_2             % x2
        x               % vse
        x_display       % zobraz a export
        xka             % vstupy trenovani
        chci            % target trenovani
        y               % output neuronky
        done            % trenovani done
        defaultnet = 1; % ovlada pouzitou sit
        train           % training data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.X1EditFieldLabel.Text = strcat('x',char(8321)); %dolni index x1
            app.X2EditFieldLabel.Text = strcat('x',char(8322)); %dolni index x2
        end

        % Button pushed function: AddPointButton
        function AddPointButtonPushed(app, event)
            app.x_1 = app.X1EditField.Value;
            app.x_2 = app.X2EditField.Value;
            if size(app.x,2) > 2
                app.x = app.x(:,1:2);
            end
            app.x = [app.x;app.x_1,app.x_2];
            app.UITable.Data = app.x;
            app.ClassifyButton.Enable = 'on';
            assignin('base','gui',app.x); %debug
            
        end

        % Value changed function: X2EditField
        function X2EditFieldValueChanged(app, event)
%             value = app.X2EditField.Value;
            
        end

        % Value changed function: X1EditField
        function X1EditFieldValueChanged(app, event)
%             value = app.X1EditField.Value;
            
        end

        % Callback function
        function YEditFieldValueChanged(app, event)
%             value = app.YEditField.Value;
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
%             newDisplayData = app.UITable.DisplayData;
            
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            rows = size(app.x,1);
            app.VisualizeButton.Enable = 'off';
            app.WritetoCSVButton.Enable = 'off';
            if size(app.x,2) > 2
                app.x = app.x(:,1:2);
            end
            if rows > 0
                app.x = app.x(1:rows-1,:);
                rows = size(app.x,1);
                if rows == 0
                    app.WritetoCSVButton.Enable = 'off';
                    app.ClassifyButton.Enable = 'off';
                end
                else
                uialert(app.ANN_app,"Nothing to undo!", "Can't undo");
            end
            app.UITable.Data = app.x;
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            if size(app.x,2) > 2
                app.x = app.x(:,1:2);
            end
            if app.defaultnet == 1
                app.y = myANN_100(app.x');
            elseif app.defaultnet == 0
                app.y = myANN(app.x');
            end
            app.x_display = [app.x,app.y'];
            app.UITable.Data = app.x_display;
            assignin('base','gui_y',app.y); %debug
            assignin('base','gui',app.x_display); %debug
            %% urceni zda lezi v elipse pro kontrolu
            for i = 1:size(app.x,1)
                if 0.4444444*(app.x(i,1)+2)^2+2.3668639*(app.x(i,2)-3)^2 < 1
                    app.x(i,3) = 1;
                else
                    app.x(i,3) = 0;
                end
            end
            app.x = [app.x,app.y'];
            app.WritetoCSVButton.Enable = 'on';
            app.VisualizeButton.Enable = 'on';
            assignin('base','gui_clasify',app.x); %debug
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            cla(app.UIAxes)
            app.greencorrectlyclassifiedLabel.Visible = 'on';
            app.redincorrectlyclassifiedLabel.Visible = 'on';
            app.greeninsideLabel.Visible = 'off';
            app.redoutsideLabel.Visible = 'off';
            %% elipsa
            r1 = 1.5;
            r2 = 0.65;
            xc = -2;
            yc = 3;
            t = linspace(0, 2*pi, 200);
            x = r1 * cos(t) + xc;
            y = r2 * sin(t) + yc;
            %% ANNvystup
            bb = 1;
            cc = 1;
            b=[0,0,0,0];
            c=[0,0,0,0];
            for i = 1:size(app.x,1)
                if app.x(i,4) > 0.420
                    app.x(i,4) = 1;
                else
                    app.x(i,4) = 0;
                end
                if app.x(i,4) == app.x(i,3)
                    c(cc,:) = app.x(i,:);
                    cc = cc+1;
                else
                    b(bb,:) = app.x(i,:);
                    bb = bb+1;
                end
            end
            hold(app.UIAxes,'on')
            assignin('base','gui_c',c); %debug
            assignin('base','gui_b',b); %debug
            app.UIAxes.XLim = [-3.99 2.01];
            app.UIAxes.YLim = [1.99 5.01];
            plot(app.UIAxes,c(:,1),c(:,2),'og');
            plot(app.UIAxes,b(:,1),b(:,2),'or');
            plot(app.UIAxes,x,y,'-b');
            assignin('base','gui_visu',app.x); %debug
        end

        % Button pushed function: WritetoCSVButton
        function WritetoCSVButtonPushed(app, event)
            datum = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            writematrix(app.x_display,strcat('ANN_export_',datum,'.csv'))
        end

        % Value changed function: NumberofpointsfortrainingEditField
        function NumberofpointsfortrainingEditFieldValueChanged(app, event)
%             value = app.NumberofpointsfortrainingEditField.Value;
            
        end

        % Value changed function: GenerateButton
        function GenerateButtonValueChanged(app, event)
            value = app.GenerateButton.Value;
            assignin('base','generbut',app.GenerateButton.Value); %debug
            app.done = 0;
            if value == 1
                N = app.NumberofpointsfortrainingEditField.Value;
                a = traingen(N);
                app.xka = [a(:,1),a(:,2)];
                app.chci = a(:,3);
                app.GenerateButton.Value = 0;
                app.TrainButton.Enable = 'on';
            end
        end

        % Value changed function: 
        % NumberofneuronsinhiddenlayerEditField
        function NumberofneuronsinhiddenlayerEditFieldValueChanged(app, event)
%             value = app.NumberofneuronsinhiddenlayerEditField.Value;
            
        end

        % Button pushed function: UsethetrainednetworkButton
        function UsethetrainednetworkButtonPushed(app, event)
            if app.done == 1
                app.defaultnet = 0;
            end
        end

        % Button pushed function: UsethedefaultnetworkButton
        function UsethedefaultnetworkButtonPushed(app, event)
            app.defaultnet = 1;
        end

        % Value changed function: TrainButton
        function TrainButtonValueChanged(app, event)
            value = app.TrainButton.Value;
            if value == 1
                [app.done] = train_advanced(app.xka,app.chci,app.NumberofneuronsinhiddenlayerEditField.Value);
                app.TrainButton.Value = 0;
            end
            app.UsethetrainednetworkButton.Enable = 'on';
        end

        % Button pushed function: ShowtrainingdataButton
        function ShowtrainingdataButtonPushed(app, event)
            cla(app.UIAxes)
            app.greencorrectlyclassifiedLabel.Visible = 'off';
            app.redincorrectlyclassifiedLabel.Visible = 'off';
            app.greeninsideLabel.Visible = 'on';
            app.redoutsideLabel.Visible = 'on';
            if app.defaultnet == 1
                app.train = load('a.mat');
                app.train = app.train.a;
                assignin('base','gui_apptrain',app.train); %debug
                app.xka = [app.train(:,1),app.train(:,2)];
                app.chci = app.train(:,3);
            end
            %% elipsa
            r1 = 1.5;
            r2 = 0.65;
            xc = -2;
            yc = 3;
            t = linspace(0, 2*pi, 200);
            x = r1 * cos(t) + xc;
            y = r2 * sin(t) + yc;
            %% rozdeleni
            bb = 1;
            cc = 1;
            b=[0,0];
            c=[0,0];
            assignin('base','gui_apptrain',app.train); %debug
            assignin('base','gui_appchci',app.chci); %debug
            for i = 1:size(app.xka,1)
                if app.chci(i,1) == 1
                    c(cc,:) = app.xka(i,:);
                    cc = cc+1;
                else
                    b(bb,:) = app.xka(i,:);
                    bb = bb+1;
                end
            end
            hold(app.UIAxes,'on')
            assignin('base','gui_c',c); %debug
            assignin('base','gui_b',b); %debug
            app.UIAxes.XLim = [-3.99 2.01];
            app.UIAxes.YLim = [1.99 5.01];
            plot(app.UIAxes,c(:,1),c(:,2),'.g');
            plot(app.UIAxes,b(:,1),b(:,2),'.r');
            plot(app.UIAxes,x,y,'-b');
            assignin('base','gui_visu',app.x); %debug
        end

        % Button pushed function: LoadCSVButton
        function LoadCSVButtonPushed(app, event)
            csv = uigetfile('*.csv');
            if isequal(csv,0)
                uialert(app.ANN_app,"No values loaded. Import cancelled by the user.", "Import Cancelled",'Icon','warning');
            else
                csv_table = readtable(csv);
                app.x = table2array(csv_table);
                app.UITable.Data = app.x;
                app.ClassifyButton.Enable = 'on';
                assignin('base','csv_tabulka',csv_table); %debug
                assignin('base','csv_arr',app.x); %debug
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ANN_app = uifigure('Visible', 'off');
            app.ANN_app.Name = 'ANN_VSC_Michalek';
            app.TabGroup = uitabgroup(app.ANN_app);
            app.InputandClassifyTab = uitab(app.TabGroup);
            app.InputandClassifyTab.Title = 'Input and Classify';
            app.InputPanel = uipanel(app.InputandClassifyTab);
            app.InputPanel.Title = 'Input';
            app.X1EditFieldLabel = uilabel(app.InputPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.InputPanel, 'numeric');
            app.X1EditField.Limits = [-4 2];
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X2EditFieldLabel = uilabel(app.InputPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.InputPanel, 'numeric');
            app.X2EditField.Limits = [2 5];
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X2EditFieldValueChanged, true);
            app.X2EditField.Value = 2;
            app.AddPointButton = uibutton(app.InputPanel, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointButtonPushed, true);
            app.AddPointButton.Text = 'Add Point';
            app.UndoButton = uibutton(app.InputPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.LoadCSVButton = uibutton(app.InputPanel, 'push');
            app.LoadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVButtonPushed, true);
            app.LoadCSVButton.Text = 'Load CSV';
            app.UITable = uitable(app.InputandClassifyTab);
            app.UITable.ColumnName = {'x1'; 'x2'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.OutputPanel = uipanel(app.InputandClassifyTab);
            app.OutputPanel.Title = 'Output';
            app.ClassifyButton = uibutton(app.OutputPanel, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Enable = 'off';
            app.ClassifyButton.Text = 'Classify';
            app.WritetoCSVButton = uibutton(app.OutputPanel, 'push');
            app.WritetoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @WritetoCSVButtonPushed, true);
            app.WritetoCSVButton.Enable = 'off';
            app.WritetoCSVButton.Text = 'Write to CSV';
            app.VisualizeTab = uitab(app.TabGroup);
            app.VisualizeTab.Title = 'Visualize';
            app.UIAxes = uiaxes(app.VisualizeTab);
            title(app.UIAxes, 'ANN Visualization')
            xlabel(app.UIAxes, 'x1')
            ylabel(app.UIAxes, 'x2')
            app.VisualizeButton = uibutton(app.VisualizeTab, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Enable = 'off';
            app.VisualizeButton.Text = 'Visualize';
            app.greencorrectlyclassifiedLabel = uilabel(app.VisualizeTab);
            app.greencorrectlyclassifiedLabel.Visible = 'off';
            app.greencorrectlyclassifiedLabel.Text = 'green = correctly classified';
            app.redincorrectlyclassifiedLabel = uilabel(app.VisualizeTab);
            app.redincorrectlyclassifiedLabel.Visible = 'off';
            app.redincorrectlyclassifiedLabel.Text = 'red = incorrectly classified';
            app.ShowtrainingdataButton = uibutton(app.VisualizeTab, 'push');
            app.ShowtrainingdataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowtrainingdataButtonPushed, true);
            app.ShowtrainingdataButton.Text = 'Show training data';
            app.greeninsideLabel = uilabel(app.VisualizeTab);
            app.greeninsideLabel.Visible = 'off';
            app.greeninsideLabel.Text = 'green = inside';
            app.redoutsideLabel = uilabel(app.VisualizeTab);
            app.redoutsideLabel.Visible = 'off';
            app.redoutsideLabel.Text = 'red = outside';
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.GeneratedatafortrainingPanel = uipanel(app.TrainTab);
            app.GeneratedatafortrainingPanel.Title = 'Generate data for training';
            app.NumberofpointsfortrainingEditFieldLabel = uilabel(app.GeneratedatafortrainingPanel);
            app.NumberofpointsfortrainingEditFieldLabel.Text = 'Number of points for training';
            app.NumberofpointsfortrainingEditField = uieditfield(app.GeneratedatafortrainingPanel, 'numeric');
            app.NumberofpointsfortrainingEditField.Limits = [10 Inf];
            app.NumberofpointsfortrainingEditField.RoundFractionalValues = 'on';
            app.NumberofpointsfortrainingEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofpointsfortrainingEditFieldValueChanged, true);
            app.NumberofpointsfortrainingEditField.Value = 100000;
            app.GenerateButton = uibutton(app.GeneratedatafortrainingPanel, 'state');
            app.GenerateButton.ValueChangedFcn = createCallbackFcn(app, @GenerateButtonValueChanged, true);
            app.GenerateButton.Text = 'Generate';
            app.TrainthenetworkPanel = uipanel(app.TrainTab);
            app.TrainthenetworkPanel.Title = 'Train the network';
            app.NumberofneuronsinhiddenlayerEditFieldLabel = uilabel(app.TrainthenetworkPanel);
            app.NumberofneuronsinhiddenlayerEditFieldLabel.Text = 'Number of neurons in hidden layer';
            app.NumberofneuronsinhiddenlayerEditField = uieditfield(app.TrainthenetworkPanel, 'numeric');
            app.NumberofneuronsinhiddenlayerEditField.Limits = [1 1000];
            app.NumberofneuronsinhiddenlayerEditField.RoundFractionalValues = 'on';
            app.NumberofneuronsinhiddenlayerEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofneuronsinhiddenlayerEditFieldValueChanged, true);
            app.NumberofneuronsinhiddenlayerEditField.Value = 100;
            app.TrainButton = uibutton(app.TrainthenetworkPanel, 'state');
            app.TrainButton.ValueChangedFcn = createCallbackFcn(app, @TrainButtonValueChanged, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.ChooseanetworkPanel = uipanel(app.TrainTab);
            app.ChooseanetworkPanel.Title = 'Choose a network';
            app.UsethetrainednetworkButton = uibutton(app.ChooseanetworkPanel, 'push');
            app.UsethetrainednetworkButton.ButtonPushedFcn = createCallbackFcn(app, @UsethetrainednetworkButtonPushed, true);
            app.UsethetrainednetworkButton.Enable = 'off';
            app.UsethetrainednetworkButton.Text = 'Use the trained network';
            app.UsethedefaultnetworkButton = uibutton(app.ChooseanetworkPanel, 'push');
            app.UsethedefaultnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @UsethedefaultnetworkButtonPushed, true);
            app.UsethedefaultnetworkButton.Text = 'Use the default network';
            % Show the figure after all components are created
            app.ANN_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ANN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ANN_app)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ANN_app)
        end
    end
end